classdef apptest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        LoadModelButton           matlab.ui.control.Button
        SaveModelButton           matlab.ui.control.Button
        currentParamLabel         matlab.ui.control.Label
        currentParamValueLabel    matlab.ui.control.Label
        t_brkSlider               matlab.ui.control.Slider
        t_brkSliderLabel          matlab.ui.control.Label
        w_brkSlider               matlab.ui.control.Slider
        w_brkSliderLabel          matlab.ui.control.Label
        kaSlider                  matlab.ui.control.Slider
        kaSliderLabel             matlab.ui.control.Label
        muSlider                  matlab.ui.control.Slider
        muSliderLabel             matlab.ui.control.Label
        xiSlider                  matlab.ui.control.Slider
        xiSliderLabel             matlab.ui.control.Label
        KuSlider                  matlab.ui.control.Slider
        KuSliderLabel             matlab.ui.control.Label
        m2Slider                  matlab.ui.control.Slider
        m2SliderLabel             matlab.ui.control.Label
        m1Slider                  matlab.ui.control.Slider
        m1SliderLabel             matlab.ui.control.Label
        RSlider                   matlab.ui.control.Slider
        RSliderLabel              matlab.ui.control.Label
        LSlider                   matlab.ui.control.Slider
        LSliderLabel              matlab.ui.control.Label
        NonlinearParametersLabel  matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = public)
        pendulum % The pendulum nonlinear system
    end
    
    methods (Access = private)
        
        function simulate_pendulum(app)
            Ts = 0.05;
            Tstop = 30;
            t = 0:Ts:Tstop; % Create a time vector from 0 to Tstop with step Ts
            nsteps = numel(t);

            pendulum = app.pendulum;
            w_disturbance = false;
            
            [pendulum, f, b, h, Fx, Bu, Hx] = pendulum.nonlinear(Ts, w_disturbance);

            n = 2 + w_disturbance;

            x0 = zeros(n, 1);
            

            R = deg2rad(0.015)^2; % Measurement noise (from datasheet)
            if size(x0, 1) == 3
                Q = diag([(0.001)^2 (0.001*Ts)^2 0.1]);
            else
                Q = diag([deg2rad(0.015)^2 deg2rad(0.015*Ts)^2]);
            end

            
            if ~exist("U_LOADED", "var")
                u = zeros(1, nsteps);
                u(1) = 25;
                u(2) = 80;
                u(3) = -15;
                u(4) = 5;
                U_LOADED = true;
            end

            [xhat, yhat] = simulate_sys(t, u, f, h, x0, true, Q, R);

            tt = t;
            
            if ~exist("MEAN_LOADED", "var")
                load("mean_impulse");
                MEAN_LOADED = true;
            end

            ax1 = app.UIAxes;
            plot(ax1, t, y, '-k', 'LineWidth', 1);
            hold(ax1,"on");
            plot(ax1, tt, rad2deg(yhat), 'LineWidth', 1);
            legend(ax1, 'Measured Impulse', 'Simulated Pendulum', 'Location', 'Best');
            % xlabel('t [s]');
            % ylabel("$\varphi [^\circ]$", 'Interpreter', 'latex');
            % title('Pendulum Angle Comparison');
            % grid minor;
            % grid on;
            hold(ax1,"off");
        end
        
        function sethintlabeldata(app, label, value)
            app.currentParamLabel.Text = label;
            if isnan(value)
                app.currentParamValueLabel.Text = "";
            else

                app.currentParamValueLabel.Text = num2str(value);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.pendulum = Pendulum();
            sys = app.pendulum;
            app.LSlider.Value = sys.L;
            app.RSlider.Value = sys.R;
            app.m1Slider.Value = sys.m1;
            app.m2Slider.Value = sys.m2;
            app.KuSlider.Value = 1;
            app.xiSlider.Value = 1;
            app.muSlider.Value = 1;
            app.kaSlider.Value = 1;
            app.w_brkSlider.Value = 1;
            app.t_brkSlider.Value = 1;

            app.sethintlabeldata("", NaN);

            app.simulate_pendulum();
        end

        % Value changing function: LSlider
        function LSliderValueChanging(app, event)
            changingValue = event.Value;
            app.pendulum = app.pendulum.setL(changingValue);
            app.sethintlabeldata("L", app.pendulum.L);
            app.simulate_pendulum();
        end

        % Value changing function: RSlider
        function RSliderValueChanging(app, event)
            changingValue = event.Value;
            app.pendulum = app.pendulum.setR(changingValue);
            app.sethintlabeldata("R", app.pendulum.R);
            app.simulate_pendulum();
        end

        % Value changing function: m1Slider
        function m1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.pendulum = app.pendulum.setm1(changingValue);
            app.sethintlabeldata("m1", app.pendulum.m1);
            app.simulate_pendulum();
        end

        % Value changing function: m2Slider
        function m2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.pendulum = app.pendulum.setm2(changingValue);
            app.sethintlabeldata("m2", app.pendulum.m2);
            app.simulate_pendulum();
        end

        % Value changing function: KuSlider
        function KuSliderValueChanging(app, event)
            changingValue = event.Value;
            app.pendulum = app.pendulum.setKu(changingValue, true);
            app.sethintlabeldata("Ku", app.pendulum.Ku);
            app.simulate_pendulum();
        end

        % Value changing function: xiSlider
        function xiSliderValueChanging(app, event)
            changingValue = event.Value;
            app.pendulum = app.pendulum.setxi(changingValue, true);
            app.sethintlabeldata("xi", app.pendulum.xi);
            app.simulate_pendulum();
        end

        % Value changing function: muSlider
        function muSliderValueChanging(app, event)
            changingValue = event.Value;
            app.pendulum = app.pendulum.setmu(changingValue, true);
            app.sethintlabeldata("mu", app.pendulum.mu);
            app.simulate_pendulum();
        end

        % Value changing function: kaSlider
        function kaSliderValueChanging(app, event)
            changingValue = event.Value;
            app.pendulum = app.pendulum.setka(changingValue, true);
            app.sethintlabeldata("ka", app.pendulum.ka);
            app.simulate_pendulum();
        end

        % Value changing function: w_brkSlider
        function w_brkSliderValueChanging(app, event)
            changingValue = event.Value;
            app.pendulum = app.pendulum.setw_brk(changingValue, true);
            app.sethintlabeldata("w_brk", app.pendulum.omega_brk);
            app.simulate_pendulum();
        end

        % Value changing function: t_brkSlider
        function t_brkSliderValueChanging(app, event)
            changingValue = event.Value;
            app.pendulum = app.pendulum.sett_brk(changingValue, true);
            app.sethintlabeldata("t_brk", app.pendulum.tau_brk);
            app.simulate_pendulum();
        end

        % Button pushed function: SaveModelButton
        function SaveModelButtonPushed(app, event)
            sys = app.pendulum;
            save("apptest_model", "sys");
        end

        % Button pushed function: LoadModelButton
        function LoadModelButtonPushed(app, event)
            load("apptest_model", "sys");
            app.pendulum = sys;
            app.simulate_pendulum();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {638, 638};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {330, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.NonlinearParametersLabel = uilabel(app.GridLayout2);
            app.NonlinearParametersLabel.Text = 'Nonlinear Parameters';
            app.LSliderLabel = uilabel(app.GridLayout2);
            app.LSliderLabel.Text = 'L';
            app.LSlider = uislider(app.GridLayout2);
            app.LSlider.Limits = [0 0.2];
            app.LSlider.ValueChangingFcn = createCallbackFcn(app, @LSliderValueChanging, true);
            app.RSliderLabel = uilabel(app.GridLayout2);
            app.RSliderLabel.Text = 'R';
            app.RSlider = uislider(app.GridLayout2);
            app.RSlider.Limits = [0 0.05];
            app.RSlider.ValueChangingFcn = createCallbackFcn(app, @RSliderValueChanging, true);
            app.m1SliderLabel = uilabel(app.GridLayout2);
            app.m1SliderLabel.Text = 'm1';
            app.m1Slider = uislider(app.GridLayout2);
            app.m1Slider.Limits = [0 0.01];
            app.m1Slider.ValueChangingFcn = createCallbackFcn(app, @m1SliderValueChanging, true);
            app.m2SliderLabel = uilabel(app.GridLayout2);
            app.m2SliderLabel.Text = 'm2';
            app.m2Slider = uislider(app.GridLayout2);
            app.m2Slider.Limits = [0 0.05];
            app.m2Slider.ValueChangingFcn = createCallbackFcn(app, @m2SliderValueChanging, true);
            app.KuSliderLabel = uilabel(app.GridLayout2);
            app.KuSliderLabel.Text = 'Ku';
            app.KuSlider = uislider(app.GridLayout2);
            app.KuSlider.Limits = [0 2];
            app.KuSlider.ValueChangingFcn = createCallbackFcn(app, @KuSliderValueChanging, true);
            app.xiSliderLabel = uilabel(app.GridLayout2);
            app.xiSliderLabel.Text = 'xi';
            app.xiSlider = uislider(app.GridLayout2);
            app.xiSlider.Limits = [0 2];
            app.xiSlider.ValueChangingFcn = createCallbackFcn(app, @xiSliderValueChanging, true);
            app.muSliderLabel = uilabel(app.GridLayout2);
            app.muSliderLabel.Text = 'mu';
            app.muSlider = uislider(app.GridLayout2);
            app.muSlider.Limits = [0 2];
            app.muSlider.ValueChangingFcn = createCallbackFcn(app, @muSliderValueChanging, true);
            app.kaSliderLabel = uilabel(app.GridLayout2);
            app.kaSliderLabel.Text = 'ka';
            app.kaSlider = uislider(app.GridLayout2);
            app.kaSlider.Limits = [0 2];
            app.kaSlider.ValueChangingFcn = createCallbackFcn(app, @kaSliderValueChanging, true);
            app.w_brkSliderLabel = uilabel(app.GridLayout2);
            app.w_brkSliderLabel.Text = 'w_brk';
            app.w_brkSlider = uislider(app.GridLayout2);
            app.w_brkSlider.Limits = [0 2];
            app.w_brkSlider.ValueChangingFcn = createCallbackFcn(app, @w_brkSliderValueChanging, true);
            app.t_brkSliderLabel = uilabel(app.GridLayout2);
            app.t_brkSliderLabel.Text = 't_brk';
            app.t_brkSlider = uislider(app.GridLayout2);
            app.t_brkSlider.Limits = [0 1000];
            app.t_brkSlider.ValueChangingFcn = createCallbackFcn(app, @t_brkSliderValueChanging, true);
            app.currentParamValueLabel = uilabel(app.GridLayout2);
            app.currentParamValueLabel.Tag = 'currentparamvalue';
            app.currentParamValueLabel.Text = 'currentParamValue';
            app.currentParamLabel = uilabel(app.GridLayout2);
            app.currentParamLabel.Tag = 'currentparam';
            app.currentParamLabel.Text = 'currentParam';
            app.SaveModelButton = uibutton(app.GridLayout2, 'push');
            app.SaveModelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveModelButtonPushed, true);
            app.SaveModelButton.Text = 'Save Model';
            app.LoadModelButton = uibutton(app.GridLayout2, 'push');
            app.LoadModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadModelButtonPushed, true);
            app.LoadModelButton.Text = 'Load Model';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Simulated System Response')
            xlabel(app.UIAxes, 't [s]')
            ylabel(app.UIAxes, 'value [-]')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = apptest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end